classdef InterpolationGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        xEditFieldLabel       matlab.ui.control.Label
        xEditField            matlab.ui.control.NumericEditField
        yEditFieldLabel       matlab.ui.control.Label
        yEditField            matlab.ui.control.NumericEditField
        AddPointButton        matlab.ui.control.Button
        queryEditFieldLabel   matlab.ui.control.Label
        queryEditField        matlab.ui.control.NumericEditField
        AddQueryButton        matlab.ui.control.Button
        GetOutputButton       matlab.ui.control.Button
        ReadFileButton        matlab.ui.control.Button
        resetButton           matlab.ui.control.Button
        PointsTextAreaLabel   matlab.ui.control.Label
        PointsTextArea        matlab.ui.control.TextArea
        QueriesTextAreaLabel  matlab.ui.control.Label
        QueriesTextArea       matlab.ui.control.TextArea
        OutputsTextAreaLabel  matlab.ui.control.Label
        OutputsTextArea       matlab.ui.control.TextArea
        UIAxes                matlab.ui.control.UIAxes
        orderEditFieldLabel   matlab.ui.control.Label
        orderEditField        matlab.ui.control.NumericEditField
        orderButton           matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
    end


    properties (Access = private)
        xArr ={};
        yArr={};
        queryArr={};
        order
        currentQuery
        out
        fun
    end

    methods (Access = private)
    
        function [] = print(app)            
            
            
%             app.OutputsTextArea.Value = str;
            
            oldOutput = strjoin(app.OutputsTextArea.Value,'\n');
            str = func2str(app.fun);
%             disp([str oldOutput]);
            lineString = "" + newline ;
            newString = num2str(app.out);
            outputString = oldOutput + lineString +lineString;
            outputString = outputString + "Query : " + app.currentQuery + newline;
            outputString = outputString + str + newline;
            outputString = outputString +"y = " +newString;
            app.OutputsTextArea.Value = outputString;  
        end
    
        function [] = printPoints(app)
            i = 1;
            while i <= numel(app.xArr)
                strX = ""+app.xArr(i);
                strY =""+app.yArr(i);
                
                oldOutput = strjoin(app.PointsTextArea.Value,'\n');
                lineString = "" + newline ;
                newString = strX + " , " +strY;
                outputString = oldOutput + lineString ;
                outputString = outputString + newString;
                app.PointsTextArea.Value = outputString;
                i = i+1;
            end  
            i = 1;
            while i <= numel(app.queryArr)
                strQ = ""+app.queryArr(i);
                oldOutput = strjoin(app.QueriesTextArea.Value,'\n');
                lineString = "" + newline ;
                newString = strQ;
                outputString = oldOutput + lineString ;
                outputString = outputString + newString;
                app.QueriesTextArea.Value = outputString;
                i = i+1;
            end  
        end
    
        function [] = plotf(app)
            x=-15:0.1:15;
            hold(app.UIAxes,'on');
            plot(app.UIAxes,x,app.fun(x));
            hold(app.UIAxes,'off');
        end
        
    end


    methods (Access = private)

        % Button pushed function: AddPointButton
        function AddPointButtonPushed(app, event)
            x = app.xEditField.Value;
            strX = "" + x;
            app.xArr = cat(1,app.xArr,x);
            
            y = app.yEditField.Value;
            strY = "" + y;
            app.yArr = cat(1,app.yArr,y);
            
            oldOutput = strjoin(app.PointsTextArea.Value,'\n');
            lineString = "" + newline ;
            newString = strX + ", " +strY;
            outputString = oldOutput + lineString ;
            outputString = outputString + newString;
            app.PointsTextArea.Value = outputString;            
        end

        % Button pushed function: AddQueryButton
        function AddQueryButtonPushed(app, event)
            q = app.queryEditField.Value;
            strQ = "" + q;
            app.queryArr = cat(1,app.queryArr,q);
            
            oldOutput = strjoin(app.QueriesTextArea.Value,'\n');
            lineString = "" + newline ;
            newString = strQ;
            outputString = oldOutput + lineString ;
            outputString = outputString + newString;
            app.QueriesTextArea.Value = outputString;            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.xArr = {};
            app.yArr = {};
            app.queryArr = {};
            cla(app.UIAxes);
            
            app.QueriesTextArea.Value ="";
            app.PointsTextArea.Value="";
            app.OutputsTextArea.Value="";
            
        end

        % Button pushed function: GetOutputButton
        function GetOutputButtonPushed(app, event)

            try
            app.queryArr = cell2mat(app.queryArr);
            catch
                
            end
            
            methodName = app.DropDown.Value;
            switch methodName
                case "Newton"
                     i = 1;
                     while i <= numel(app.queryArr)
                         [app.out,app.fun] = newton_interpolation(app.order,app.xArr,app.yArr,app.queryArr(i));
                         app.currentQuery = app.queryArr(i);
                         i = i+1;
                         print(app);
                         plotf(app);
                     end
                case "Lagrange"
                     i = 1;
                     while i <= numel(app.queryArr)
%                          disp("wwwwwaaaw");
%                             disp(app.xArr);
%                             disp(app.yArr);
%                             disp(app.queryArr(i));
                         [app.out,app.fun] = Lagrange(app.order,app.xArr,app.yArr,app.queryArr(i));
                         app.currentQuery = app.queryArr(i);
                         print(app);
                         plotf(app);
                         i = i+1;
                     end
                otherwise                    
            end
            
%             app.queryArr = cell2mat(app.queryArr);
%             methodName = app.DropDown.Value;
%             switch methodName
%                 case "Newton"
%                     %while
%                     [out,fun] = newton_interpolation(app.order,app.xArr,app.yArr,app.queryArr(1));
%                 case "Lagrange"
%                     [out,fun] = Lagrange(app.order,app.xArr,app.yArr,app.queryArr(1));
%                 otherwise
%                     
%             end
%             str = func2str(fun);
%             app.OutputsTextArea.Value = str;
%             
%             oldOutput = str;
%             disp([str oldOutput]);
%             lineString = "" + newline ;
%             newString = num2str(out);
%             outputString = oldOutput + lineString ;
%             outputString = outputString + newString;
%             app.OutputsTextArea.Value = outputString;  
            
        end

        % Button pushed function: orderButton
        function orderButtonPushed(app, event)
            app.order = app.orderEditField.Value;
        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
            [methodName,app.order,app.xArr,app.yArr,app.queryArr] = fileReader2("input2.txt");
            app.DropDown.Value = methodName;
%             disp("wwwwww");
%             disp(app.xArr);
%             disp(app.yArr);
%             disp(app.queryArr(1));
            
            app.orderEditField.Value = app.order;
%             x = cell2mat(app.queryArr);
%             app.QueriesTextArea.Value = app.queryArr;
            printPoints(app);   
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddPointButton = uibutton(app.UIFigure, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointButtonPushed, true);
            app.AddPointButton.Text = 'Add Point';
            app.queryEditFieldLabel = uilabel(app.UIFigure);
            app.queryEditFieldLabel.Text = 'query';
            app.queryEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddQueryButton = uibutton(app.UIFigure, 'push');
            app.AddQueryButton.ButtonPushedFcn = createCallbackFcn(app, @AddQueryButtonPushed, true);
            app.AddQueryButton.Text = 'Add Query';
            app.GetOutputButton = uibutton(app.UIFigure, 'push');
            app.GetOutputButton.ButtonPushedFcn = createCallbackFcn(app, @GetOutputButtonPushed, true);
            app.GetOutputButton.Text = 'Get Output';
            app.ReadFileButton = uibutton(app.UIFigure, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'Read File';
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.PointsTextAreaLabel = uilabel(app.UIFigure);
            app.PointsTextAreaLabel.Text = 'Points';
            app.PointsTextArea = uitextarea(app.UIFigure);
            app.PointsTextArea.Editable = 'off';
            app.QueriesTextAreaLabel = uilabel(app.UIFigure);
            app.QueriesTextAreaLabel.Text = 'Queries';
            app.QueriesTextArea = uitextarea(app.UIFigure);
            app.QueriesTextArea.Editable = 'off';
            app.OutputsTextAreaLabel = uilabel(app.UIFigure);
            app.OutputsTextAreaLabel.Text = 'Outputs';
            app.OutputsTextArea = uitextarea(app.UIFigure);
            app.OutputsTextArea.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.orderEditFieldLabel = uilabel(app.UIFigure);
            app.orderEditFieldLabel.Text = 'order';
            app.orderEditField = uieditfield(app.UIFigure, 'numeric');
            app.orderEditField.Value = 1;
            app.orderButton = uibutton(app.UIFigure, 'push');
            app.orderButton.ButtonPushedFcn = createCallbackFcn(app, @orderButtonPushed, true);
            app.orderButton.Text = 'order';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Newton', 'Lagrange'};
            app.DropDown.Value = 'Lagrange';
        end
    end

    methods (Access = public)

        % Construct app
        function app = InterpolationGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end